classdef HB11TEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        InputGrid                       matlab.ui.container.GridLayout
        InputNavigationBar              matlab.ui.container.TabGroup
        InputLaserTab                   matlab.ui.container.Tab
        InputLaserGrid                  matlab.ui.container.GridLayout
        InputLaserPanel                 matlab.ui.container.Panel
        InputLaserSubGrid               matlab.ui.container.GridLayout
        LaserEfficiencyDecimalFormEditField_2  matlab.ui.control.EditField
        LaserEfficiencyDecimalFormEditField_2Label  matlab.ui.control.Label
        RepetitionRateHzEditField_2     matlab.ui.control.EditField
        RepetitionRateHzEditField_2Label  matlab.ui.control.Label
        TotalLaserEnergyPerPulseMJEditField_2  matlab.ui.control.EditField
        TotalLaserEnergyPerPulseMJEditField_2Label  matlab.ui.control.Label
        InputReactorTab                 matlab.ui.container.Tab
        InputReactorGrid                matlab.ui.container.GridLayout
        ReactorPanel                    matlab.ui.container.Panel
        InputReactorSubGrid             matlab.ui.container.GridLayout
        DutyCycleDecimalFormEditField_2  matlab.ui.control.EditField
        DutyCycleDecimalFormEditField_2Label  matlab.ui.control.Label
        IncreasedGainbyXFactorRatioEditField_2  matlab.ui.control.EditField
        IncreasedGainbyXFactorRatioEditField_2Label  matlab.ui.control.Label
        TargetGainRatioEditField_2      matlab.ui.control.EditField
        TargetGainRatioEditField_2Label  matlab.ui.control.Label
        InputPowerTab                   matlab.ui.container.Tab
        InputPowerGrid                  matlab.ui.container.GridLayout
        InputPowerPanel                 matlab.ui.container.Panel
        InputPowerSubGrid               matlab.ui.container.GridLayout
        ElectricityRateMWhEditField_2   matlab.ui.control.EditField
        ElectricityRateMWhEditField_2Label  matlab.ui.control.Label
        ElectricityGeneratorEfficiencyDecimalFormEditField_2  matlab.ui.control.EditField
        ElectricityGeneratorEfficiencyDecimalFormEditField_2Label  matlab.ui.control.Label
        InputFuelManTab                 matlab.ui.container.Tab
        InputFuelManGrid                matlab.ui.container.GridLayout
        InputFuelManPanel               matlab.ui.container.Panel
        InputFuelManSubGrid             matlab.ui.container.GridLayout
        CapacitiveCoilandHydrogenConeBooleanEditField_2  matlab.ui.control.EditField
        CapacitiveCoilandHydrogenConeBooleanEditField_2Label  matlab.ui.control.Label
        TotalCostofXFactorEditField_2   matlab.ui.control.EditField
        TotalCostofXFactorEditField_2Label  matlab.ui.control.Label
        XFactorBooleanEditField_2       matlab.ui.control.EditField
        XFactorBooleanEditField_2Label  matlab.ui.control.Label
        FuelStoreTab                    matlab.ui.container.Tab
        InputFuelStoreGrid              matlab.ui.container.GridLayout
        InputFuelStorePanel             matlab.ui.container.Panel
        InputFuelStoreSubGrid           matlab.ui.container.GridLayout
        InputMaintenanceTab             matlab.ui.container.Tab
        InputMaintenanceGrid            matlab.ui.container.GridLayout
        InputMaintenancePanel           matlab.ui.container.Panel
        InputMaintenanceSubGrid         matlab.ui.container.GridLayout
        NumberofYearsinServiceYearsEditField_2  matlab.ui.control.EditField
        NumberofYearsinServiceYearsEditField_2Label  matlab.ui.control.Label
        InputConstructionTab            matlab.ui.container.Tab
        InputConstructionGrid           matlab.ui.container.GridLayout
        InputConstructionPanel          matlab.ui.container.Panel
        InputConstructionSubGrid        matlab.ui.container.GridLayout
        InputCoolingTab                 matlab.ui.container.Tab
        InputCoolingGrid                matlab.ui.container.GridLayout
        InputCoolingPanel               matlab.ui.container.Panel
        InputCoolingSubGrid             matlab.ui.container.GridLayout
        InputPersonnelTab               matlab.ui.container.Tab
        InputPersonnelGrid              matlab.ui.container.GridLayout
        InputPersonnelPanel             matlab.ui.container.Panel
        InputPersonnelSubGrid           matlab.ui.container.GridLayout
        NumberofStaffStaffEditField_2   matlab.ui.control.EditField
        NumberofStaffStaffEditField_2Label  matlab.ui.control.Label
        InputVacuumTab                  matlab.ui.container.Tab
        InputVacuumGrid                 matlab.ui.container.GridLayout
        InputVacuumPanel                matlab.ui.container.Panel
        InputPanelSubGrid               matlab.ui.container.GridLayout
        MassFlowRateLsEditField_2       matlab.ui.control.EditField
        MassFlowRateLsEditField_2Label  matlab.ui.control.Label
        InputActions                    matlab.ui.container.GridLayout
        RunModelButton                  matlab.ui.control.Button
        OutputsTab                      matlab.ui.container.Tab
        OutputGrid                      matlab.ui.container.GridLayout
        OutputNavigationbar             matlab.ui.container.TabGroup
        OutputLaserTab                  matlab.ui.container.Tab
        OutputLaserGrid                 matlab.ui.container.GridLayout
        OutputLaserPanel                matlab.ui.container.Panel
        OutputLaserSubPanel             matlab.ui.container.GridLayout
        OutputReactorTab                matlab.ui.container.Tab
        OutputReactorGrid               matlab.ui.container.GridLayout
        OutputReactorPanel              matlab.ui.container.Panel
        OutputReactorSubGrid            matlab.ui.container.GridLayout
        ReactionOutputThermalMJEditField_2  matlab.ui.control.EditField
        ReactionOutputThermalMJEditField_2Label  matlab.ui.control.Label
        OutputPowerTab                  matlab.ui.container.Tab
        OutputPowerGrid                 matlab.ui.container.GridLayout
        OutputPowerPanel                matlab.ui.container.Panel
        OutputReactorSubPanel           matlab.ui.container.GridLayout
        YearlyNetPowerOutputMWhEditField_2  matlab.ui.control.EditField
        YearlyNetPowerOutputMWhEditField_2Label  matlab.ui.control.Label
        HoursInOperationPerYearHrsEditField_2  matlab.ui.control.EditField
        HoursInOperationPerYearHrsEditField_2Label  matlab.ui.control.Label
        NetPowerOutputMWhEditField      matlab.ui.control.EditField
        NetPowerOutputMWhEditFieldLabel  matlab.ui.control.Label
        TotalReactorEnergyConsumptionMWhEditField_4  matlab.ui.control.EditField
        TotalReactorEnergyConsumptionMWhEditField_4Label  matlab.ui.control.Label
        GrossPowerOutputMWhEditField    matlab.ui.control.EditField
        GrossPowerOutputMWhEditFieldLabel  matlab.ui.control.Label
        RealisedReactorGainRatioEditField_2  matlab.ui.control.EditField
        RealisedReactorGainRatioEditField_2Label  matlab.ui.control.Label
        OutputFuelManTab                matlab.ui.container.Tab
        OutputFuelManGrid               matlab.ui.container.GridLayout
        OutputFuelManPanel              matlab.ui.container.Panel
        OutputFuelManSubGrid            matlab.ui.container.GridLayout
        TotalFuelMaufacturingCostsOngoingYearEditField  matlab.ui.control.EditField
        TotalFuelMaufacturingCostsOngoingYearEditFieldLabel  matlab.ui.control.Label
        TotalFuelManufacturingCostsUpfrontEditField_2  matlab.ui.control.EditField
        TotalFuelManufacturingCostsUpfrontEditField_2Label  matlab.ui.control.Label
        TotalCostPerTargetEditField_2   matlab.ui.control.EditField
        TotalCostPerTargetEditField_2Label  matlab.ui.control.Label
        OutputFuelStoreTab              matlab.ui.container.Tab
        OutputFuelStoreGrid             matlab.ui.container.GridLayout
        OutputFuelStorePanel            matlab.ui.container.Panel
        OutputFuelStoreSubGrid          matlab.ui.container.GridLayout
        TotalCostofFuelDeliveryandStorageEditField_2  matlab.ui.control.EditField
        TotalCostofFuelDeliveryandStorageEditField_2Label  matlab.ui.control.Label
        OutputMaintenanceTab            matlab.ui.container.Tab
        OutputMaintenanceGrid           matlab.ui.container.GridLayout
        OutputMaintenancePanel          matlab.ui.container.Panel
        OutputMaintenanceSubGrid        matlab.ui.container.GridLayout
        LifetimeMaintenanceCostEditField_2  matlab.ui.control.EditField
        LifetimeMaintenanceCostEditField_2Label  matlab.ui.control.Label
        OutputConstructionTab           matlab.ui.container.Tab
        OutputConstructionGrid          matlab.ui.container.GridLayout
        OutputConstructionPanel         matlab.ui.container.Panel
        OutputConstructionSubGrid       matlab.ui.container.GridLayout
        TotalConstructionCostEditField_2  matlab.ui.control.EditField
        TotalConstructionCostEditField_2Label  matlab.ui.control.Label
        OutputCoolingTab                matlab.ui.container.Tab
        OutputCoolingGrid               matlab.ui.container.GridLayout
        OutputCoolingPanel              matlab.ui.container.Panel
        OutputCoolingSubGrid            matlab.ui.container.GridLayout
        OutputPersonnelTab              matlab.ui.container.Tab
        OutputPersonnelGrid             matlab.ui.container.GridLayout
        OutputPersonnelPanel            matlab.ui.container.Panel
        OutputPersonnelSubPanel         matlab.ui.container.GridLayout
        YearlyPersonnelCostEditField_2  matlab.ui.control.EditField
        YearlyPersonnelCostEditField_2Label  matlab.ui.control.Label
        OutputVacuumTab                 matlab.ui.container.Tab
        OutputVacuumGrid                matlab.ui.container.GridLayout
        OutputVacuumPanel               matlab.ui.container.Panel
        OutputVacuumSubGrid             matlab.ui.container.GridLayout
        TotalCostofVacuumSystemEditField  matlab.ui.control.EditField
        TotalCostofVacuumSystemEditFieldLabel  matlab.ui.control.Label
        OutputOverallTab                matlab.ui.container.Tab
        OutputOverallGrid               matlab.ui.container.GridLayout
        OutputOverallPanel              matlab.ui.container.Panel
        OutputOverallSubGrid            matlab.ui.container.GridLayout
        LifetimeProfitWithoutInflationEditField_2  matlab.ui.control.EditField
        LifetimeProfitWithoutInflationEditField_2Label  matlab.ui.control.Label
        LifetimeCostWithoutInflationEditField_2  matlab.ui.control.EditField
        LifetimeCostWithoutInflationEditField_2Label  matlab.ui.control.Label
        YearlyProfitEditField_2         matlab.ui.control.EditField
        YearlyProfitEditField_2Label    matlab.ui.control.Label
        YearlyGrossRevenueEditField_2   matlab.ui.control.EditField
        YearlyGrossRevenueEditField_2Label  matlab.ui.control.Label
        YearlyCostEditField_2           matlab.ui.control.EditField
        YearlyCostEditField_2Label      matlab.ui.control.Label
        CapitalCostEditField_2          matlab.ui.control.EditField
        CapitalCostEditField_2Label     matlab.ui.control.Label
        OutputLCOETab                   matlab.ui.container.Tab
        OutputLCOEGrid                  matlab.ui.container.GridLayout
        OutputLCOEPanel                 matlab.ui.container.Panel
        OutputLCOESubGrid               matlab.ui.container.GridLayout
        LCOEMWhEditField_2              matlab.ui.control.EditField
        LCOEMWhEditField_2Label         matlab.ui.control.Label
        CostofDecomissioningMWhEditField_2  matlab.ui.control.EditField
        CostofDecomissioningMWhEditField_2Label  matlab.ui.control.Label
        AnnualCostofFuelEditField_2     matlab.ui.control.EditField
        AnnualCostofFuelEditField_2Label  matlab.ui.control.Label
        AnnualCostofScheduledPartReplacementsEditField_2  matlab.ui.control.EditField
        AnnualCostofScheduledPartReplacementsEditField_2Label  matlab.ui.control.Label
        AnnualCostofOperationsandMaintenanceEditField_2  matlab.ui.control.EditField
        AnnualCostofOperationsandMaintenanceEditField_2Label  matlab.ui.control.Label
        AnnualCostofUpfrontCapitalEditField_2  matlab.ui.control.EditField
        AnnualCostofUpfrontCapitalEditField_2Label  matlab.ui.control.Label
        OutputProjectionsTab            matlab.ui.container.Tab
        OutputProjectionsGrid           matlab.ui.container.Panel
        OutputProjectionsSubGrid        matlab.ui.container.GridLayout
        OutputProjectionsSubSubGrid     matlab.ui.container.GridLayout
        ProjectedLifetimeNetRevenueEditField  matlab.ui.control.EditField
        ProjectedLifetimeNetRevenueEditFieldLabel  matlab.ui.control.Label
        ProjectedLifetimeGrossRevenueEditField  matlab.ui.control.EditField
        ProjectedLifetimeGrossRevenueEditFieldLabel  matlab.ui.control.Label
        ProjectedLifetimeCostEditField  matlab.ui.control.EditField
        ProjectedLifetimeCostEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        OptionsLayout                   matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        SavethissessionLabel            matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        OpenaprevioussessionLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppLayer = ApplicationModule() % Instance of the Application Layer
    end
    
    methods (Access = private)
        
        % p_MapApplicationLayerToInputs : Sets inputs to data in store
        function p_MapApplicationLayerToInputs(app)

            % --- Extract Input Values from Application Layer ---
            inputs = app.AppLayer.PostInputs();

            % --- Load Into Input Fields ---
            children = app.InputPanelLayout.Children;

            % Check that number of children is even
            num_children = length(children);

            if mod(num_children, 2) ~= 0
                error("Failed to parse inputs.");
            end
            
            % Note : This loop depends on assumptions 1 and 2 detailed above
            for i = 1:2:(num_children - 1)
                
                % Process Key (Remove Spaces, Set Lowercase append input specifier)
                key = children(i).Text;
                key = strrep(key, " ", "");
                key = lower(key);
                key = strcat("in_", key);
                
                % Set Value
                children(i+1).Value = inputs.(key);

            end
            
        end
        
        function p_PlotElectricityGenerated(app, outputs)
            elec_out_data = outputs.("out_electricitygenerated");
            periods = elec_out_data.("period");
            outputs = elec_out_data.("output");
            plot(app.ElectricityGenerationAxes, periods, outputs);

        end
        
        function p_MapApplicationLayerToOutputs(app)
            
            % --- Extract Outputs from Application Layer ---
            outputs = app.AppLayer.PostOutputs();

            % --- Load Into UI Outputs ---
            app.p_PlotElectricityGenerated(outputs);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function OutputNavTreeSelectionChanged(app, event)
            
            % Array of selected TreeNodes
            selectedNodes = app.OutputNavTree.SelectedNodes;

            % --- Check if the node is a parent (Has Children) ---
            if ~isempty(selectedNodes.Children)
                return;
            end
            
            % --- Change the Title ---
            selectedOutputName= selectedNodes.Text;
            app.OutputPanel.Title = selectedOutputName;

            % --- Change the Rendered Content Panel ---

            % Clear all selected panels
            set(app.OutputPanel.Children, 'Visible', 'off');
            
            % Conditionally show a child panel
            switch selectedOutputName
           
            end


        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Get File Path
            [filename, path] = uigetfile('*.xml');
            
            % Handle the user cancelling
            if filename == 0
                return;
            end
            
            % Load File into Memory
            app.AppLayer.OpenState(strcat(path, filename));
            app.p_MapApplicationLayerToInputs();
            app.p_MapApplicationLayerToOutputs();

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Select Output Destination
            [filename, path] = uiputfile('*.xml');

            % Handle User Cancel
            if filename == 0
                return;
            end

            % Save to specified file
            app.AppLayer.SaveState(strcat(path, filename));

        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            
            % --- Hardcoded plot [DELETE THIS] ---
            % Assuming time starts from 0 and increments by 1 year for each data point
            data = [2445966369.40	2439545763.84	2433021762.35	2426393679.74	2419660827.16	2412822512.02	2405878038.04	2398826705.20	2391667809.71	2384400644.01	2377024496.74	2369538652.75	2361942393.02	2354234994.71	2346415731.11	2338483871.61	2330438681.70	2322279422.94	2314005352.95	2305615725.39	2297109789.92	2288486792.23	2279745973.96	2270886572.71	2261907822.03	2252808951.38	2243589186.13	2234247747.52	2224783852.66	2215196714.48	2205485541.75	2195649539.03	2185687906.65	2175599840.72	2165384533.06	2155041171.22	2144568938.46	2133967013.70	2123234571.50	2112370782.08	2101374811.27	2090245820.46	2078982966.65	2067585402.36	2056052275.64	2044382730.06	2032575904.66	2020630933.95	2008546947.86	1996323071.76	1983958426.40	1971452127.92	1958803287.79	1946011012.82	1933074405.13	1919992562.10	1906764576.40	1893389535.91	1879866523.75	1866194618.22	1852372892.78	1838400416.04	1824276251.74	1809999458.71	1795569090.85	1780984197.13	1766243821.51	1751347003.00	1736292775.55	1721080168.08	1705708204.45	1690175903.40	1674482278.59	1658626338.49	1642607086.44	1626423520.58	1610074633.82	1593559413.83	1576876843.02	1560025898.52	1543005552.10	1525814770.23	1508452513.98	1490917739.05	1473209395.70	1455326428.75	1437267777.55	1419032375.94	1400619152.25	1382027029.24	1363254924.11	1344301748.45	1325166408.20	1305847803.66	1286344829.45	1266656374.45	1246781321.81	1226718548.94	1206466927.41	1186025323.00	1165392595.62	1144567599.31	1123549182.20	1102336186.49	1080927448.40	1059321798.19	1037518060.06	1015515052.19	993311586.67	970906469.48	948298500.48	925486473.35	902469175.57	879245388.41	855813886.88	832173439.70	808322809.30	784260751.74	759986016.71	735497347.51	710793480.99	685873147.55	660735071.08	635377968.95	609800551.98	584001524.38	557979583.77	531733421.08	505261720.59	478563159.86	451636409.68	424480134.08	397092990.29	369473628.68	341620692.74	313532819.07	285208637.30	256646770.13	227845833.21	198804435.18	169521177.59	139994654.88	110223454.37	80206156.18	49941333.25	19427551.26	-11336631.40	-42352663.62	-73622001.65	-105146109.12	-136926457.09	-168964524.04	-201261795.96	-233819766.35	-266639936.26	-299723814.36	-333072916.90	-366688767.85	-400572898.83	-434726849.23	-469152166.19	-503850404.67	-538823127.49	-574071905.32	-609598316.78	-645403948.44	-681490394.85	-717859258.60	-754512150.38	-791450688.93	-828676501.19	-866191222.26	-903996495.47	-942093972.41	-980485312.97	-1019172185.38	-1058156266.25	-1097439240.61	-1137022801.95	-1176908652.26	-1217098502.05	-1257594070.43	-1298397085.12	-1339509282.50	-1380932407.64	-1422668214.37	-1464718465.27	-1507084931.78	-1549769394.18	-1592773641.66	-1636099472.35	-1679748693.39	-1723723120.94	-1768024580.21	-1812654905.57	-1857615940.52	-1902909537.76	-1948537559.25	-1994501876.23	-2040804369.27	-2087446928.32	-2134431452.74	-2181759851.37	-2229434042.54	-2277455954.14	-2325827523.65	-2374550698.21	-2423627434.61	-2473059699.42	-2522849468.94	-2572998729.32	-2623509476.58	-2674383716.65	-2725623465.40	-2777230748.75	-2829207602.63	-2881556073.10	-2934278216.37	-2987376098.82	-3040851797.09	-3094707398.12	-3148944999.16	-3203566707.89	-3258574642.39	-3313970931.23	-3369757713.53	-3425937138.97	-3482511367.88	-3539482571.26	-3596852930.84	-3654624639.14	-3712799899.49	-3771380926.14	-3830369944.23	-3889769189.90	-3949580910.34	-4009807363.79	-4070450819.65	-4131513558.51	-4192997872.19	-4254906063.81	-4317240447.82	-4380003350.09	-4443197107.93	-4506824070.15	-4570886597.12	-4635387060.82	-4700327844.90	-4765711344.72	-4831539967.43	-4897816131.98	-4964542269.23	-5031720821.96	-5099354244.96	-5167445005.05	-5235995581.16	-5305008464.39	-5374486158.05	-5444431177.73	-5514846051.34	-5585733319.17	-5657095533.99	-5728935261.04	-5801255078.13	-5874057575.68	-5947345356.82	-6021121037.37	-6095387245.97	-6170146624.12	-6245401826.21	-6321155519.62	-6397410384.77	-6474169115.15	-6551434417.42	-6629209011.44	-6707495630.37	-6786297020.68	-6865615942.25	-6945455168.42	-7025817486.07	-7106705695.62	-7188122611.18	-7270071060.55	-7352553885.32	-7435573940.88	-7519134096.57	-7603237235.66	-7687886255.46	-7773084067.39	-7858833596.99	-7945137784.07	-8031999582.69	-8119421961.30	-8207407902.74	-8295960404.37	-8385082478.08	-8474777150.40	-8565047462.53	-8655896470.43	-8747327244.92	-8839342871.65];
            time = 0:numel(data)-1;
        
            % Plot the data on the axes
            plot(app.UIAxes, time, data, 'LineWidth', 2);
        
            % Set labels for the axes
            xlabel(app.UIAxes, 'Time (Months)');
            ylabel(app.UIAxes, 'Total Project Cost ($)');
        
            % Optionally, you can set the title for the plot
            title(app.UIAxes, 'Total Project Cost vs Time');
        
            % Optionally, you can adjust the axis limits for a better view
            xlim(app.UIAxes, [0, numel(data)-1]);
            ylim(app.UIAxes, [min(data), max(data)]);

            app.UIAxes.XTick = 0:10:numel(data)-1;

            tick_jump = (max(data) - min(data))/10; % 10 Ticks

            app.UIAxes.YTick = min(data):tick_jump:max(data);
            
            % Optionally, you can use scientific notation for large numbers on the y-axis
            % app.UIAxes.YAxis.Exponent = 9;
            % app.UIAxes.YAxis.TickLabelFormat = '%.1fE9';
            return;

            % --- Load Inputs into the application layer ---

            % Assumption 1 : Children = [ lbl1, val1, lbl2, val2, ... ]
            % Assumption 2 : Even number of children

            input_data = struct();

            children = app.InputPanelLayout.Children;

            % Check that number of children is even
            num_children = length(children);

            if mod(num_children, 2) ~= 0
                error("Failed to parse inputs.");
            end
            
            % Note : This loop depends on assumptions 1 and 2 detailed above
            for i = 1:2:(num_children - 1)
                
                % Process Key (Remove Spaces, Set Lowercase append input specifier)
                key = children(i).Text;
                key = strrep(key, " ", "");
                key = lower(key);
                key = strcat("in_", key);
                
                % Process Value
                value = children(i+1).Value;
                
                % Insert into Struct
                input_data.(key) = value;

            end
           
            % Load into the application layer
            app.AppLayer.LoadInputs(input_data);

            % --- Run the Model ---
            app.AppLayer.RunModel();
         
            % --- Update Outputs in UI ---
            app.p_MapApplicationLayerToOutputs();
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputGrid = uigridlayout(app.InputsTab);
            app.InputActions = uigridlayout(app.InputGrid);
            app.RunModelButton = uibutton(app.InputActions, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.InputNavigationBar = uitabgroup(app.InputGrid);
            app.InputLaserTab = uitab(app.InputNavigationBar);
            app.InputLaserTab.Title = 'Laser';
            app.InputLaserGrid = uigridlayout(app.InputLaserTab);
            app.InputLaserPanel = uipanel(app.InputLaserGrid);
            app.InputLaserPanel.Title = 'Primary Laser';
            app.InputLaserSubGrid = uigridlayout(app.InputLaserPanel);
            app.TotalLaserEnergyPerPulseMJEditField_2Label = uilabel(app.InputLaserSubGrid);
            app.TotalLaserEnergyPerPulseMJEditField_2Label.Text = 'Total Laser Energy Per Pulse (MJ)';
            app.TotalLaserEnergyPerPulseMJEditField_2 = uieditfield(app.InputLaserSubGrid, 'text');
            app.TotalLaserEnergyPerPulseMJEditField_2.Editable = 'off';
            app.TotalLaserEnergyPerPulseMJEditField_2.Value = '20';
            app.RepetitionRateHzEditField_2Label = uilabel(app.InputLaserSubGrid);
            app.RepetitionRateHzEditField_2Label.Text = 'Repetition Rate (Hz)';
            app.RepetitionRateHzEditField_2 = uieditfield(app.InputLaserSubGrid, 'text');
            app.RepetitionRateHzEditField_2.Editable = 'off';
            app.RepetitionRateHzEditField_2.Value = '1';
            app.LaserEfficiencyDecimalFormEditField_2Label = uilabel(app.InputLaserSubGrid);
            app.LaserEfficiencyDecimalFormEditField_2Label.Text = 'Laser Efficiency (% Decimal Form)';
            app.LaserEfficiencyDecimalFormEditField_2 = uieditfield(app.InputLaserSubGrid, 'text');
            app.LaserEfficiencyDecimalFormEditField_2.Editable = 'off';
            app.LaserEfficiencyDecimalFormEditField_2.Value = '0.036';
            app.InputReactorTab = uitab(app.InputNavigationBar);
            app.InputReactorTab.Title = 'Reactor';
            app.InputReactorGrid = uigridlayout(app.InputReactorTab);
            app.ReactorPanel = uipanel(app.InputReactorGrid);
            app.ReactorPanel.Title = 'Reactor';
            app.InputReactorSubGrid = uigridlayout(app.ReactorPanel);
            app.TargetGainRatioEditField_2Label = uilabel(app.InputReactorSubGrid);
            app.TargetGainRatioEditField_2Label.Text = 'Target Gain (Ratio)';
            app.TargetGainRatioEditField_2 = uieditfield(app.InputReactorSubGrid, 'text');
            app.TargetGainRatioEditField_2.Editable = 'off';
            app.TargetGainRatioEditField_2.Value = '150';
            app.IncreasedGainbyXFactorRatioEditField_2Label = uilabel(app.InputReactorSubGrid);
            app.IncreasedGainbyXFactorRatioEditField_2Label.Text = 'Increased Gain by X Factor (Ratio)';
            app.IncreasedGainbyXFactorRatioEditField_2 = uieditfield(app.InputReactorSubGrid, 'text');
            app.IncreasedGainbyXFactorRatioEditField_2.Editable = 'off';
            app.IncreasedGainbyXFactorRatioEditField_2.Value = '2';
            app.DutyCycleDecimalFormEditField_2Label = uilabel(app.InputReactorSubGrid);
            app.DutyCycleDecimalFormEditField_2Label.Text = 'Duty Cycle (% Decimal Form)';
            app.DutyCycleDecimalFormEditField_2 = uieditfield(app.InputReactorSubGrid, 'text');
            app.DutyCycleDecimalFormEditField_2.Editable = 'off';
            app.DutyCycleDecimalFormEditField_2.Value = '0.66';
            app.InputPowerTab = uitab(app.InputNavigationBar);
            app.InputPowerTab.Title = 'Power';
            app.InputPowerGrid = uigridlayout(app.InputPowerTab);
            app.InputPowerPanel = uipanel(app.InputPowerGrid);
            app.InputPowerPanel.Title = 'Power Generation';
            app.InputPowerSubGrid = uigridlayout(app.InputPowerPanel);
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2Label = uilabel(app.InputPowerSubGrid);
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2Label.Text = 'Electricity Generator Efficiency (% Decimal Form)';
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2 = uieditfield(app.InputPowerSubGrid, 'text');
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2.Editable = 'off';
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2.Value = '0.4';
            app.ElectricityRateMWhEditField_2Label = uilabel(app.InputPowerSubGrid);
            app.ElectricityRateMWhEditField_2Label.Text = 'Electricity Rate ($/MWh)';
            app.ElectricityRateMWhEditField_2 = uieditfield(app.InputPowerSubGrid, 'text');
            app.ElectricityRateMWhEditField_2.Editable = 'off';
            app.ElectricityRateMWhEditField_2.Value = '72';
            app.InputFuelManTab = uitab(app.InputNavigationBar);
            app.InputFuelManTab.Title = 'Fuel Man';
            app.InputFuelManGrid = uigridlayout(app.InputFuelManTab);
            app.InputFuelManPanel = uipanel(app.InputFuelManGrid);
            app.InputFuelManPanel.Title = 'Fuel Manufacturing';
            app.InputFuelManSubGrid = uigridlayout(app.InputFuelManPanel);
            app.XFactorBooleanEditField_2Label = uilabel(app.InputFuelManSubGrid);
            app.XFactorBooleanEditField_2Label.Text = 'X Factor (Boolean)';
            app.XFactorBooleanEditField_2 = uieditfield(app.InputFuelManSubGrid, 'text');
            app.XFactorBooleanEditField_2.Editable = 'off';
            app.XFactorBooleanEditField_2.Value = '0';
            app.TotalCostofXFactorEditField_2Label = uilabel(app.InputFuelManSubGrid);
            app.TotalCostofXFactorEditField_2Label.Text = 'Total Cost of X Factor ($)';
            app.TotalCostofXFactorEditField_2 = uieditfield(app.InputFuelManSubGrid, 'text');
            app.TotalCostofXFactorEditField_2.Editable = 'off';
            app.TotalCostofXFactorEditField_2.Value = '100';
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2Label = uilabel(app.InputFuelManSubGrid);
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2Label.Text = 'Capacitive Coil and Hydrogen Cone (Boolean)';
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2 = uieditfield(app.InputFuelManSubGrid, 'text');
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2.Editable = 'off';
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2.Value = '1';
            app.FuelStoreTab = uitab(app.InputNavigationBar);
            app.FuelStoreTab.Title = 'Fuel Store';
            app.InputFuelStoreGrid = uigridlayout(app.FuelStoreTab);
            app.InputFuelStorePanel = uipanel(app.InputFuelStoreGrid);
            app.InputFuelStorePanel.Title = 'Fuel Storage / Delivery';
            app.InputFuelStoreSubGrid = uigridlayout(app.InputFuelStorePanel);
            app.InputMaintenanceTab = uitab(app.InputNavigationBar);
            app.InputMaintenanceTab.Title = 'Maintenance';
            app.InputMaintenanceGrid = uigridlayout(app.InputMaintenanceTab);
            app.InputMaintenancePanel = uipanel(app.InputMaintenanceGrid);
            app.InputMaintenancePanel.Title = 'Maintenance';
            app.InputMaintenanceSubGrid = uigridlayout(app.InputMaintenancePanel);
            app.NumberofYearsinServiceYearsEditField_2Label = uilabel(app.InputMaintenanceSubGrid);
            app.NumberofYearsinServiceYearsEditField_2Label.Text = 'Number of Years in Service (Years)';
            app.NumberofYearsinServiceYearsEditField_2 = uieditfield(app.InputMaintenanceSubGrid, 'text');
            app.NumberofYearsinServiceYearsEditField_2.Editable = 'off';
            app.NumberofYearsinServiceYearsEditField_2.Value = '25';
            app.InputConstructionTab = uitab(app.InputNavigationBar);
            app.InputConstructionTab.Title = 'Construction';
            app.InputConstructionGrid = uigridlayout(app.InputConstructionTab);
            app.InputConstructionPanel = uipanel(app.InputConstructionGrid);
            app.InputConstructionPanel.Title = 'Construction';
            app.InputConstructionSubGrid = uigridlayout(app.InputConstructionPanel);
            app.InputCoolingTab = uitab(app.InputNavigationBar);
            app.InputCoolingTab.Title = 'Cooling';
            app.InputCoolingGrid = uigridlayout(app.InputCoolingTab);
            app.InputCoolingPanel = uipanel(app.InputCoolingGrid);
            app.InputCoolingPanel.Title = 'Cooling System';
            app.InputCoolingSubGrid = uigridlayout(app.InputCoolingPanel);
            app.InputPersonnelTab = uitab(app.InputNavigationBar);
            app.InputPersonnelTab.Title = 'Personnel';
            app.InputPersonnelGrid = uigridlayout(app.InputPersonnelTab);
            app.InputPersonnelPanel = uipanel(app.InputPersonnelGrid);
            app.InputPersonnelPanel.Title = 'Personnel';
            app.InputPersonnelSubGrid = uigridlayout(app.InputPersonnelPanel);
            app.NumberofStaffStaffEditField_2Label = uilabel(app.InputPersonnelSubGrid);
            app.NumberofStaffStaffEditField_2Label.Text = 'Number of Staff (Staff)';
            app.NumberofStaffStaffEditField_2 = uieditfield(app.InputPersonnelSubGrid, 'text');
            app.NumberofStaffStaffEditField_2.Editable = 'off';
            app.NumberofStaffStaffEditField_2.Value = '300';
            app.InputVacuumTab = uitab(app.InputNavigationBar);
            app.InputVacuumTab.Title = 'Vacuum';
            app.InputVacuumGrid = uigridlayout(app.InputVacuumTab);
            app.InputVacuumPanel = uipanel(app.InputVacuumGrid);
            app.InputVacuumPanel.Title = 'Vacuum System';
            app.InputPanelSubGrid = uigridlayout(app.InputVacuumPanel);
            app.MassFlowRateLsEditField_2Label = uilabel(app.InputPanelSubGrid);
            app.MassFlowRateLsEditField_2Label.Text = 'Mass Flow Rate (L/s)';
            app.MassFlowRateLsEditField_2 = uieditfield(app.InputPanelSubGrid, 'text');
            app.MassFlowRateLsEditField_2.Editable = 'off';
            app.MassFlowRateLsEditField_2.Value = '32000';
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputGrid = uigridlayout(app.OutputsTab);
            app.OutputNavigationbar = uitabgroup(app.OutputGrid);
            app.OutputLaserTab = uitab(app.OutputNavigationbar);
            app.OutputLaserTab.Title = 'Laser';
            app.OutputLaserGrid = uigridlayout(app.OutputLaserTab);
            app.OutputLaserPanel = uipanel(app.OutputLaserGrid);
            app.OutputLaserPanel.Title = 'Primary Laser';
            app.OutputLaserSubPanel = uigridlayout(app.OutputLaserPanel);
            app.OutputReactorTab = uitab(app.OutputNavigationbar);
            app.OutputReactorTab.Title = 'Reactor';
            app.OutputReactorGrid = uigridlayout(app.OutputReactorTab);
            app.OutputReactorPanel = uipanel(app.OutputReactorGrid);
            app.OutputReactorPanel.Title = 'Reactor';
            app.OutputReactorSubGrid = uigridlayout(app.OutputReactorPanel);
            app.ReactionOutputThermalMJEditField_2Label = uilabel(app.OutputReactorSubGrid);
            app.ReactionOutputThermalMJEditField_2Label.Text = 'Reaction Output Thermal (MJ)';
            app.ReactionOutputThermalMJEditField_2 = uieditfield(app.OutputReactorSubGrid, 'text');
            app.ReactionOutputThermalMJEditField_2.Editable = 'off';
            app.ReactionOutputThermalMJEditField_2.Value = '3000';
            app.OutputPowerTab = uitab(app.OutputNavigationbar);
            app.OutputPowerTab.Title = 'Power';
            app.OutputPowerGrid = uigridlayout(app.OutputPowerTab);
            app.OutputPowerPanel = uipanel(app.OutputPowerGrid);
            app.OutputPowerPanel.Title = 'Power Generation';
            app.OutputReactorSubPanel = uigridlayout(app.OutputPowerPanel);
            app.RealisedReactorGainRatioEditField_2Label = uilabel(app.OutputReactorSubPanel);
            app.RealisedReactorGainRatioEditField_2Label.Text = 'Realised Reactor Gain (Ratio)';
            app.RealisedReactorGainRatioEditField_2 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.RealisedReactorGainRatioEditField_2.Editable = 'off';
            app.RealisedReactorGainRatioEditField_2.Value = '2.16';
            app.GrossPowerOutputMWhEditFieldLabel = uilabel(app.OutputReactorSubPanel);
            app.GrossPowerOutputMWhEditFieldLabel.Text = 'Gross Power Output (MWh)';
            app.GrossPowerOutputMWhEditField = uieditfield(app.OutputReactorSubPanel, 'text');
            app.GrossPowerOutputMWhEditField.Editable = 'off';
            app.GrossPowerOutputMWhEditField.Value = '1200';
            app.TotalReactorEnergyConsumptionMWhEditField_4Label = uilabel(app.OutputReactorSubPanel);
            app.TotalReactorEnergyConsumptionMWhEditField_4Label.Text = 'Total Reactor Energy Consumption (MWh)';
            app.TotalReactorEnergyConsumptionMWhEditField_4 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.TotalReactorEnergyConsumptionMWhEditField_4.Editable = 'off';
            app.TotalReactorEnergyConsumptionMWhEditField_4.Value = '592.538';
            app.NetPowerOutputMWhEditFieldLabel = uilabel(app.OutputReactorSubPanel);
            app.NetPowerOutputMWhEditFieldLabel.Text = 'Net Power Output (MWh)';
            app.NetPowerOutputMWhEditField = uieditfield(app.OutputReactorSubPanel, 'text');
            app.NetPowerOutputMWhEditField.Editable = 'off';
            app.NetPowerOutputMWhEditField.Value = '607.462';
            app.HoursInOperationPerYearHrsEditField_2Label = uilabel(app.OutputReactorSubPanel);
            app.HoursInOperationPerYearHrsEditField_2Label.Text = 'Hours In Operation Per Year (Hrs)';
            app.HoursInOperationPerYearHrsEditField_2 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.HoursInOperationPerYearHrsEditField_2.Editable = 'off';
            app.HoursInOperationPerYearHrsEditField_2.Value = '5781.6';
            app.YearlyNetPowerOutputMWhEditField_2Label = uilabel(app.OutputReactorSubPanel);
            app.YearlyNetPowerOutputMWhEditField_2Label.Text = 'Yearly Net Power Output (MWh)';
            app.YearlyNetPowerOutputMWhEditField_2 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.YearlyNetPowerOutputMWhEditField_2.Editable = 'off';
            app.YearlyNetPowerOutputMWhEditField_2.Value = '3512103.67';
            app.OutputFuelManTab = uitab(app.OutputNavigationbar);
            app.OutputFuelManTab.Title = 'Fuel Man';
            app.OutputFuelManGrid = uigridlayout(app.OutputFuelManTab);
            app.OutputFuelManPanel = uipanel(app.OutputFuelManGrid);
            app.OutputFuelManPanel.Title = 'Fuel Manufacturing';
            app.OutputFuelManSubGrid = uigridlayout(app.OutputFuelManPanel);
            app.TotalCostPerTargetEditField_2Label = uilabel(app.OutputFuelManSubGrid);
            app.TotalCostPerTargetEditField_2Label.Text = 'Total Cost Per Target ($)';
            app.TotalCostPerTargetEditField_2 = uieditfield(app.OutputFuelManSubGrid, 'text');
            app.TotalCostPerTargetEditField_2.Editable = 'off';
            app.TotalCostPerTargetEditField_2.Value = '0.45';
            app.TotalFuelManufacturingCostsUpfrontEditField_2Label = uilabel(app.OutputFuelManSubGrid);
            app.TotalFuelManufacturingCostsUpfrontEditField_2Label.Text = 'Total Fuel Manufacturing Costs Upfront ($)';
            app.TotalFuelManufacturingCostsUpfrontEditField_2 = uieditfield(app.OutputFuelManSubGrid, 'text');
            app.TotalFuelManufacturingCostsUpfrontEditField_2.Editable = 'off';
            app.TotalFuelManufacturingCostsUpfrontEditField_2.Value = '150000000';
            app.TotalFuelMaufacturingCostsOngoingYearEditFieldLabel = uilabel(app.OutputFuelManSubGrid);
            app.TotalFuelMaufacturingCostsOngoingYearEditFieldLabel.Text = 'Total Fuel Maufacturing Costs Ongoing ($/Year)';
            app.TotalFuelMaufacturingCostsOngoingYearEditField = uieditfield(app.OutputFuelManSubGrid, 'text');
            app.TotalFuelMaufacturingCostsOngoingYearEditField.Editable = 'off';
            app.TotalFuelMaufacturingCostsOngoingYearEditField.Value = '13411542.58';
            app.OutputFuelStoreTab = uitab(app.OutputNavigationbar);
            app.OutputFuelStoreTab.Title = 'Fuel Store';
            app.OutputFuelStoreGrid = uigridlayout(app.OutputFuelStoreTab);
            app.OutputFuelStorePanel = uipanel(app.OutputFuelStoreGrid);
            app.OutputFuelStorePanel.Title = 'Fuel Storage and Delivery';
            app.OutputFuelStoreSubGrid = uigridlayout(app.OutputFuelStorePanel);
            app.TotalCostofFuelDeliveryandStorageEditField_2Label = uilabel(app.OutputFuelStoreSubGrid);
            app.TotalCostofFuelDeliveryandStorageEditField_2Label.Text = 'Total Cost of Fuel Delivery and Storage ($)';
            app.TotalCostofFuelDeliveryandStorageEditField_2 = uieditfield(app.OutputFuelStoreSubGrid, 'text');
            app.TotalCostofFuelDeliveryandStorageEditField_2.Editable = 'off';
            app.TotalCostofFuelDeliveryandStorageEditField_2.Value = '1173788.80';
            app.OutputMaintenanceTab = uitab(app.OutputNavigationbar);
            app.OutputMaintenanceTab.Title = 'Maintenance';
            app.OutputMaintenanceGrid = uigridlayout(app.OutputMaintenanceTab);
            app.OutputMaintenancePanel = uipanel(app.OutputMaintenanceGrid);
            app.OutputMaintenancePanel.Title = 'Maintenance';
            app.OutputMaintenanceSubGrid = uigridlayout(app.OutputMaintenancePanel);
            app.LifetimeMaintenanceCostEditField_2Label = uilabel(app.OutputMaintenanceSubGrid);
            app.LifetimeMaintenanceCostEditField_2Label.Text = 'Lifetime Maintenance Cost ($)';
            app.LifetimeMaintenanceCostEditField_2 = uieditfield(app.OutputMaintenanceSubGrid, 'text');
            app.LifetimeMaintenanceCostEditField_2.Editable = 'off';
            app.LifetimeMaintenanceCostEditField_2.Value = '746405239.5';
            app.OutputConstructionTab = uitab(app.OutputNavigationbar);
            app.OutputConstructionTab.Title = 'Construction';
            app.OutputConstructionGrid = uigridlayout(app.OutputConstructionTab);
            app.OutputConstructionPanel = uipanel(app.OutputConstructionGrid);
            app.OutputConstructionPanel.Title = 'Construction';
            app.OutputConstructionSubGrid = uigridlayout(app.OutputConstructionPanel);
            app.TotalConstructionCostEditField_2Label = uilabel(app.OutputConstructionSubGrid);
            app.TotalConstructionCostEditField_2Label.Text = 'Total Construction Cost ($)';
            app.TotalConstructionCostEditField_2 = uieditfield(app.OutputConstructionSubGrid, 'text');
            app.TotalConstructionCostEditField_2.Editable = 'off';
            app.TotalConstructionCostEditField_2.Value = '1146.2985';
            app.OutputCoolingTab = uitab(app.OutputNavigationbar);
            app.OutputCoolingTab.Title = 'Cooling';
            app.OutputCoolingGrid = uigridlayout(app.OutputCoolingTab);
            app.OutputCoolingPanel = uipanel(app.OutputCoolingGrid);
            app.OutputCoolingPanel.Title = 'Cooling System';
            app.OutputCoolingSubGrid = uigridlayout(app.OutputCoolingPanel);
            app.OutputPersonnelTab = uitab(app.OutputNavigationbar);
            app.OutputPersonnelTab.Title = 'Personnel';
            app.OutputPersonnelGrid = uigridlayout(app.OutputPersonnelTab);
            app.OutputPersonnelPanel = uipanel(app.OutputPersonnelGrid);
            app.OutputPersonnelPanel.Title = 'Personnel';
            app.OutputPersonnelSubPanel = uigridlayout(app.OutputPersonnelPanel);
            app.YearlyPersonnelCostEditField_2Label = uilabel(app.OutputPersonnelSubPanel);
            app.YearlyPersonnelCostEditField_2Label.Text = 'Yearly Personnel Cost ($)';
            app.YearlyPersonnelCostEditField_2 = uieditfield(app.OutputPersonnelSubPanel, 'text');
            app.YearlyPersonnelCostEditField_2.Editable = 'off';
            app.YearlyPersonnelCostEditField_2.Value = '45803604';
            app.OutputVacuumTab = uitab(app.OutputNavigationbar);
            app.OutputVacuumTab.Title = 'Vacuum';
            app.OutputVacuumGrid = uigridlayout(app.OutputVacuumTab);
            app.OutputVacuumPanel = uipanel(app.OutputVacuumGrid);
            app.OutputVacuumPanel.Title = 'Vacuum System';
            app.OutputVacuumSubGrid = uigridlayout(app.OutputVacuumPanel);
            app.TotalCostofVacuumSystemEditFieldLabel = uilabel(app.OutputVacuumSubGrid);
            app.TotalCostofVacuumSystemEditFieldLabel.Text = 'Total Cost of Vacuum System ($)';
            app.TotalCostofVacuumSystemEditField = uieditfield(app.OutputVacuumSubGrid, 'text');
            app.TotalCostofVacuumSystemEditField.Editable = 'off';
            app.TotalCostofVacuumSystemEditField.Value = '53703335.57';
            app.OutputOverallTab = uitab(app.OutputNavigationbar);
            app.OutputOverallTab.Title = 'Overall';
            app.OutputOverallGrid = uigridlayout(app.OutputOverallTab);
            app.OutputOverallPanel = uipanel(app.OutputOverallGrid);
            app.OutputOverallPanel.Title = 'Overall';
            app.OutputOverallSubGrid = uigridlayout(app.OutputOverallPanel);
            app.CapitalCostEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.CapitalCostEditField_2Label.Text = 'Capital Cost ($)';
            app.CapitalCostEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.CapitalCostEditField_2.Editable = 'off';
            app.CapitalCostEditField_2.Value = '2438543756.38';
            app.YearlyCostEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.YearlyCostEditField_2Label.Text = 'Yearly Cost ($)';
            app.YearlyCostEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.YearlyCostEditField_2.Editable = 'off';
            app.YearlyCostEditField_2.Value = '89071356.16';
            app.YearlyGrossRevenueEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.YearlyGrossRevenueEditField_2Label.Text = 'Yearly Gross Revenue ($)';
            app.YearlyGrossRevenueEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.YearlyGrossRevenueEditField_2.Editable = 'off';
            app.YearlyGrossRevenueEditField_2.Value = '252871464.26';
            app.YearlyProfitEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.YearlyProfitEditField_2Label.Text = 'Yearly Profit ($)';
            app.YearlyProfitEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.YearlyProfitEditField_2.Editable = 'off';
            app.YearlyProfitEditField_2.Value = '163800108.10';
            app.LifetimeCostWithoutInflationEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.LifetimeCostWithoutInflationEditField_2Label.Text = 'Lifetime Cost Without Inflation ($)';
            app.LifetimeCostWithoutInflationEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.LifetimeCostWithoutInflationEditField_2.Editable = 'off';
            app.LifetimeCostWithoutInflationEditField_2.Value = '4665327660.44';
            app.LifetimeProfitWithoutInflationEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.LifetimeProfitWithoutInflationEditField_2Label.Text = 'Lifetime Profit Without Inflation ($)';
            app.LifetimeProfitWithoutInflationEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.LifetimeProfitWithoutInflationEditField_2.Editable = 'off';
            app.LifetimeProfitWithoutInflationEditField_2.Value = '4095002702.51';
            app.OutputLCOETab = uitab(app.OutputNavigationbar);
            app.OutputLCOETab.Title = 'LCOE';
            app.OutputLCOEGrid = uigridlayout(app.OutputLCOETab);
            app.OutputLCOEPanel = uipanel(app.OutputLCOEGrid);
            app.OutputLCOEPanel.Title = 'LCOE';
            app.OutputLCOESubGrid = uigridlayout(app.OutputLCOEPanel);
            app.AnnualCostofUpfrontCapitalEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofUpfrontCapitalEditField_2Label.Text = 'Annual Cost of Upfront Capital ($)';
            app.AnnualCostofUpfrontCapitalEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofUpfrontCapitalEditField_2.Editable = 'off';
            app.AnnualCostofUpfrontCapitalEditField_2.Value = '97541750.26';
            app.AnnualCostofOperationsandMaintenanceEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofOperationsandMaintenanceEditField_2Label.Text = 'Annual Cost of Operations and Maintenance ($)';
            app.AnnualCostofOperationsandMaintenanceEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofOperationsandMaintenanceEditField_2.Editable = 'off';
            app.AnnualCostofOperationsandMaintenanceEditField_2.Value = '66454773.58';
            app.AnnualCostofScheduledPartReplacementsEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofScheduledPartReplacementsEditField_2Label.Text = 'Annual Cost of Scheduled Part Replacements ($)';
            app.AnnualCostofScheduledPartReplacementsEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofScheduledPartReplacementsEditField_2.Editable = 'off';
            app.AnnualCostofScheduledPartReplacementsEditField_2.Value = '9205040.00';
            app.AnnualCostofFuelEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofFuelEditField_2Label.Text = 'Annual Cost of Fuel ($)';
            app.AnnualCostofFuelEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofFuelEditField_2.Editable = 'off';
            app.AnnualCostofFuelEditField_2.Value = '13411542.58';
            app.CostofDecomissioningMWhEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.CostofDecomissioningMWhEditField_2Label.Text = 'Cost of Decomissioning ($/MWh)';
            app.CostofDecomissioningMWhEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.CostofDecomissioningMWhEditField_2.Editable = 'off';
            app.CostofDecomissioningMWhEditField_2.Value = '1.27370';
            app.LCOEMWhEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.LCOEMWhEditField_2Label.Text = 'LCOE ($/MWh)';
            app.LCOEMWhEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.LCOEMWhEditField_2.Editable = 'off';
            app.LCOEMWhEditField_2.Value = '105.27';
            app.OutputProjectionsTab = uitab(app.OutputNavigationbar);
            app.OutputProjectionsTab.Title = 'Projections';
            app.OutputProjectionsGrid = uipanel(app.OutputProjectionsTab);
            app.OutputProjectionsGrid.Title = 'Financial Projections';
            app.OutputProjectionsSubGrid = uigridlayout(app.OutputProjectionsGrid);
            app.UIAxes = uiaxes(app.OutputProjectionsSubGrid);
            title(app.UIAxes, 'Total Project Cost vs Time')
            xlabel(app.UIAxes, 'Time (Years)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OutputProjectionsSubSubGrid = uigridlayout(app.OutputProjectionsSubGrid);
            app.ProjectedLifetimeCostEditFieldLabel = uilabel(app.OutputProjectionsSubSubGrid);
            app.ProjectedLifetimeCostEditFieldLabel.Text = 'Projected Lifetime Cost ($)';
            app.ProjectedLifetimeCostEditField = uieditfield(app.OutputProjectionsSubSubGrid, 'text');
            app.ProjectedLifetimeCostEditField.Editable = 'off';
            app.ProjectedLifetimeCostEditField.Value = '11717889804.88';
            app.ProjectedLifetimeGrossRevenueEditFieldLabel = uilabel(app.OutputProjectionsSubSubGrid);
            app.ProjectedLifetimeGrossRevenueEditFieldLabel.Text = 'Projected Lifetime Gross Revenue ($)';
            app.ProjectedLifetimeGrossRevenueEditField = uieditfield(app.OutputProjectionsSubSubGrid, 'text');
            app.ProjectedLifetimeGrossRevenueEditField.Editable = 'off';
            app.ProjectedLifetimeGrossRevenueEditField.Value = '20557232676.54';
            app.ProjectedLifetimeNetRevenueEditFieldLabel = uilabel(app.OutputProjectionsSubSubGrid);
            app.ProjectedLifetimeNetRevenueEditFieldLabel.Text = 'Projected Lifetime Net Revenue ($)';
            app.ProjectedLifetimeNetRevenueEditField = uieditfield(app.OutputProjectionsSubSubGrid, 'text');
            app.ProjectedLifetimeNetRevenueEditField.Editable = 'off';
            app.ProjectedLifetimeNetRevenueEditField.Value = '8839342871.65';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.OptionsLayout = uigridlayout(app.OptionsTab);
            app.OpenaprevioussessionLabel = uilabel(app.OptionsLayout);
            app.OpenaprevioussessionLabel.Text = 'Open a previous session';
            app.OpenButton = uibutton(app.OptionsLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SavethissessionLabel = uilabel(app.OptionsLayout);
            app.SavethissessionLabel.Text = 'Save this session';
            app.SaveButton = uibutton(app.OptionsLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HB11TEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end