classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TotalCostEditField          matlab.ui.control.EditField
        TotalCostEditFieldLabel     matlab.ui.control.Label
        CalculateEconButton         matlab.ui.control.Button
        PriceEditFieldLabel         matlab.ui.control.Label
        PriceEditField              matlab.ui.control.EditField
        CalculatePhysButton         matlab.ui.control.Button
        TotalBurnEditField          matlab.ui.control.EditField
        TotalBurnEditFieldLabel     matlab.ui.control.Label
        BurnTimeEditFieldLabel      matlab.ui.control.Label
        BurnTimeEditField           matlab.ui.control.EditField
        BurnRateEditField           matlab.ui.control.EditField
        BurnRateEditFieldLabel      matlab.ui.control.Label
        EconomicsStuffLabel         matlab.ui.control.Label
        PhysicsStuffLabel           matlab.ui.control.Label
        SUPERSIMPLEGUIEXAMPLELabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        % Hack to modify path to get around locked GUI code by MATLAB
        pathoutput = addpath("ControlModule/");
        CTRL = ControlModule(); % Instantiate the control module
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculatePhysButton
        function CalculatePhysButtonPushed(app, event)
            
            % Extract variables from fields
            burn_rate = app.BurnRateEditField.Value;
            burn_time = app.BurnTimeEditField.Value;
            
            % Run calculation and set result into field
            app.TotalBurnEditField.Value = app.CTRL.RunPhysDemo(burn_rate, burn_time);
        end

        % Button pushed function: CalculateEconButton
        function CalculateEconButtonPushed(app, event)
            
            % Extract variables from fields
            fuel_price = app.PriceEditField.Value;

            % Run calculation and set result into field
            app.TotalCostEditField.Value = app.CTRL.RunEconDemo(fuel_price);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SUPERSIMPLEGUIEXAMPLELabel = uilabel(app.UIFigure);
            app.SUPERSIMPLEGUIEXAMPLELabel.Text = 'SUPER SIMPLE GUI EXAMPLE';
            app.PhysicsStuffLabel = uilabel(app.UIFigure);
            app.PhysicsStuffLabel.Text = 'Physics Stuff';
            app.EconomicsStuffLabel = uilabel(app.UIFigure);
            app.EconomicsStuffLabel.Text = 'Economics Stuff';
            app.BurnRateEditFieldLabel = uilabel(app.UIFigure);
            app.BurnRateEditFieldLabel.Text = 'Burn Rate';
            app.BurnRateEditField = uieditfield(app.UIFigure, 'text');
            app.BurnRateEditField.InputType = 'digits';
            app.BurnTimeEditField = uieditfield(app.UIFigure, 'text');
            app.BurnTimeEditField.InputType = 'digits';
            app.BurnTimeEditFieldLabel = uilabel(app.UIFigure);
            app.BurnTimeEditFieldLabel.Text = 'Burn Time';
            app.TotalBurnEditFieldLabel = uilabel(app.UIFigure);
            app.TotalBurnEditFieldLabel.Text = 'Total Burn';
            app.TotalBurnEditField = uieditfield(app.UIFigure, 'text');
            app.TotalBurnEditField.InputType = 'digits';
            app.CalculatePhysButton = uibutton(app.UIFigure, 'push');
            app.CalculatePhysButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePhysButtonPushed, true);
            app.CalculatePhysButton.Text = 'Calculate';
            app.PriceEditField = uieditfield(app.UIFigure, 'text');
            app.PriceEditField.InputType = 'digits';
            app.PriceEditFieldLabel = uilabel(app.UIFigure);
            app.PriceEditFieldLabel.Text = 'Price';
            app.CalculateEconButton = uibutton(app.UIFigure, 'push');
            app.CalculateEconButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateEconButtonPushed, true);
            app.CalculateEconButton.Text = 'Calculate';
            app.TotalCostEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCostEditFieldLabel.Text = 'Total Cost';
            app.TotalCostEditField = uieditfield(app.UIFigure, 'text');
            app.TotalCostEditField.InputType = 'digits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end